classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Panel_4                 matlab.ui.container.Panel
        PreSwitch               matlab.ui.control.Switch
        PreSwitchLabel          matlab.ui.control.Label
        EditFieldTextArea       matlab.ui.control.TextArea
        EditFieldTextAreaLabel  matlab.ui.control.Label
        BrowseButton            matlab.ui.control.Button
        ControlPanel            matlab.ui.container.Panel
        SpeedColor              matlab.ui.control.Image
        ResultLandUse           matlab.ui.control.Label
        Slider2                 matlab.ui.control.Slider
        Slider2Label            matlab.ui.control.Label
        PageIndicatorField      matlab.ui.control.TextArea
        Label_2                 matlab.ui.control.Label
        EarliestButton          matlab.ui.control.Button
        PreviousButton          matlab.ui.control.Button
        NextButton              matlab.ui.control.Button
        LatestButton            matlab.ui.control.Button
        OutputButton            matlab.ui.control.Button
        PlayButton              matlab.ui.control.Button
        ModeSlider              matlab.ui.control.Slider
        Label                   matlab.ui.control.Label
        ColorModeDropDown       matlab.ui.control.DropDown
        ColorModeLabel          matlab.ui.control.Label
        Panel_2                 matlab.ui.container.Panel
        GridLayout3             matlab.ui.container.GridLayout
        LandUseButton           matlab.ui.control.Button
        SizeButton              matlab.ui.control.Button
        SpeedButton             matlab.ui.control.Button
        AbsoluteButton          matlab.ui.control.Button
        TimelapseButton         matlab.ui.control.Button
        SliderButton            matlab.ui.control.Button
        ViewButton              matlab.ui.control.Button
        Panel_3                 matlab.ui.container.Panel
        GridLayout2             matlab.ui.container.GridLayout
        AnalyzeButton           matlab.ui.control.Button
        LatestDropDown          matlab.ui.control.DropDown
        LatestDropDownLabel     matlab.ui.control.Label
        EarliestDropDown        matlab.ui.control.DropDown
        EarliestDropDownLabel   matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ImageFolder          % Folder path selected by user
        ImageList            % Sorted list of image filenames
        AlignedImages        % Aligned/preprocessed image data
        AlignedImagesGray        % 灰度对齐结果
        AlignedImagesRGB         % RGB对齐结果

        TransformParams      % Registration parameters from preprocessing
        idxEarliest          % Index of the earliest selected image
        idxLatest            % Index of the latest selected image
        RGBButton
        GrayButton
        CurrentMode          char    
        FolderPathEditField  matlab.ui.control.EditField
        currentIndex = 1
        imgEarliest
        imgLatest
        EarliestLatestSubset     % Subset of images for paging
        PreprocessingEnabled logical = false;
        CurrentDetectionMode char
        DetectionResults struct
        AbsolutePages  % Cell array for absolute mode images to page through
        TimelapseSequence   % Cell array of images for time-lapse playback
        AbsolutePagesGray   % store grayscale result pages
        AbsolutePagesRGB    % store RGB result pages
 
    end

    
   methods (Access = private) 

    function showModeControls(app, mode)
        % Hide all mode controls first
        
        app.ColorModeDropDown.Visible = 'off';
        app.ModeSlider.Visible = 'off';
        app.PlayButton.Visible = 'off';

        app.EarliestButton.Visible = 'off';
        app.PreviousButton.Visible = 'off';
        app.NextButton.Visible = 'off';
        app.LatestButton.Visible = 'off';
        app.PageIndicatorField.Visible = 'off';
        app.ColorModeLabel.Visible = 'off';
        app.ResultLandUse.Visible ='off';
        app.SpeedColor.Visible="off";

        % Display the required
        
        switch mode
            case 'Nothing'
            case 'Difference'
                app.ColorModeDropDown.Visible = 'on';

            case 'Slider'
                app.ModeSlider.Visible = 'on';        
                app.imgEarliest = app.AlignedImages{app.idxEarliest};
                app.imgLatest = app.AlignedImages{app.idxLatest};

                app.ModeSlider.Value = 50;
                % Initial display
                app.updateSliderBlend();
                
            case 'Absolute'
                % app.ModeSlider.Visible = 'on';
                app.EarliestButton.Visible = 'on';
                app.PreviousButton.Visible = 'on';
                app.NextButton.Visible = 'on';
                app.LatestButton.Visible = 'on';
                app.PageIndicatorField.Visible = 'on';
                app.ColorModeDropDown.Visible = 'on';
                app.ColorModeLabel.Visible ="on";

            case 'Time-lapse'
                if numel(app.AlignedImages) < 10
                    uialert(app.UIFigure, ...
                        'Need at least 10 images for time-lapse.', 'Error');
                else
                    app.PlayButton.Visible = 'on';
                end

            case 'EarliestLatest'
                
                app.EarliestButton.Visible = 'on';
                app.PreviousButton.Visible = 'on';
                app.NextButton.Visible = 'on';
                app.LatestButton.Visible = 'on';
                app.PageIndicatorField.Visible = 'on';
                app.currentIndex = 1;
                app.updateImageDisplay();

            case 'Speed'
                app.SpeedColor.Visible="on";
                disp('Switched to SPEED detection mode.');


            case 'Size'
                disp('Switched to SIZE detection mode.');

            case 'LandUse'
                app.ResultLandUse.Visible='on';
                app.EarliestButton.Visible = 'on';
                app.PreviousButton.Visible = 'on';
                app.NextButton.Visible = 'on';
                app.LatestButton.Visible = 'on';
                app.PageIndicatorField.Visible = 'on';
                app.currentIndex = 1;
                app.updateImageDisplay();
                disp('Switched to LAND USE detection mode.');
        end

    end

    function updateImageDisplay(app)
        % Determine pages to show
        if strcmp(app.CurrentMode, 'EarliestLatest')
            pages = app.EarliestLatestSubset;
        elseif strcmp(app.CurrentMode, 'Absolute')
            pages = app.AbsolutePages;
        else
            pages = app.AlignedImages;
        end

        % Handle empty
        if isempty(pages)
            cla(app.UIAxes);
            app.PageIndicatorField.Value = '0 / 0';
            return;
        end

        % Prevent out-of-bounds
        total = numel(pages);
        if app.currentIndex < 1
            app.currentIndex = 1;
        elseif app.currentIndex > total
            app.currentIndex = total;
        end

        % Show
        imshow(pages{app.currentIndex}, 'Parent', app.UIAxes);
        axis(app.UIAxes, 'off');
        app.PageIndicatorField.Value = sprintf('%d / %d', app.currentIndex, total);

    end

    function updateSliderBlend(app)

        t = app.ModeSlider.Value / 100;

        % Linear Blending
        img1 = im2double(app.imgEarliest);
        img2 = im2double(app.imgLatest);

        % Ensure consistent size
        if ~isequal(size(img1), size(img2))
            uialert(app.UIFigure, 'Images are not the same size.', 'Error');
            return;
        end

        blended = (1 - t) * img1 + t * img2;

        imshow(blended, 'Parent', app.UIAxes);
        axis(app.UIAxes, 'off');
    end

    function showDetectionResult(app, mode)
        mode = lower(mode);

        if ~isfield(app.DetectionResults, mode) || isempty(app.DetectionResults.(mode))
            uialert(app.UIFigure, sprintf('No detection result for mode: %s', mode), 'Error');
            return;
        end

        result = app.DetectionResults.(mode);

        % Prioritize visual display
        if isfield(result, 'visual') && ~isempty(result.visual)
            if iscell(result.visual)
                imshow(result.visual{1}, 'Parent', app.UIAxes);
            else
                imshow(result.visual, 'Parent', app.UIAxes);
            end
            axis(app.UIAxes, 'off');
            return;
        end

        % Fallback to visual_gray
        if isfield(result, 'visual_gray') && ~isempty(result.visual_gray)
            if iscell(result.visual_gray)
                imshow(result.visual_gray{2}, 'Parent', app.UIAxes);
            else
                imshow(result.visual_gray, 'Parent', app.UIAxes);
            end
            axis(app.UIAxes, 'off');
            return;
        end
        % If there is a heatmap
        if isfield(result, 'heatmap') && ~isempty(result.heatmap)
            imagesc(result.heatmap, 'Parent', app.UIAxes);
            axis(app.UIAxes, 'off');
            colorbar(app.UIAxes);
            return;
        end

        % No visible results
        uialert(app.UIFigure, sprintf('No displayable content for mode: %s', mode), 'Error');
    end


end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            % Ask user to select a folder
            folderPath = uigetdir;
            if folderPath == 0
                uialert(app.UIFigure, 'No folder selected.', 'Error');
                figure(app.UIFigure);
                return;
            end
            app.ImageFolder = folderPath;
            app.EditFieldTextArea.Value = folderPath;
            % Get all image files in the selected folder
            imgFormats = {'*.jpg','*.jpeg','*.png','*.tif','*.bmp'};
            files = [];
            for i = 1:length(imgFormats)
                files = [files; dir(fullfile(folderPath, imgFormats{i}))];
            end

            if isempty(files)
                uialert(app.UIFigure, 'No image files found in the selected folder.', 'Error');
                figure(app.UIFigure);
                return;
            end

            % Extract filenames
            fileList = {files.name};

            sortedList = sort(fileList);

            % Store sorted list in app properties
            app.ImageList = sortedList;
            % Call the preprocessing module
            if app.PreprocessingEnabled
                disp('Preprocessing is ON. Calling registration module...');
                
                [app.AlignedImagesGray, app.AlignedImagesRGB, app.TransformParams] = register_images(folderPath, sortedList);
                app.AlignedImages = app.AlignedImagesRGB;
                % Placeholder: Just imread (since you don't have the module yet)
                % app.AlignedImages = cellfun(@(f) imread(fullfile(folderPath,f)), sortedList, 'UniformOutput', false);
                % app.TransformParams = [];

            else
                disp('Preprocessing is OFF. Just loading raw images...');
                app.AlignedImages = cellfun(@(f) imread(fullfile(folderPath,f)), sortedList, 'UniformOutput', false);
                app.TransformParams = [];
            end
            app.showModeControls('Nothing');
            cla(app.UIAxes);
            figure(app.UIFigure);

            % Update dropdown menus
            app.EarliestDropDown.Items = sortedList;
            app.LatestDropDown.Items = sortedList;
            % Set default selections
            app.EarliestDropDown.Value = sortedList{1};
            app.LatestDropDown.Value = sortedList{end};

            % Record default idx
            app.idxEarliest = 1;
            app.idxLatest = numel(sortedList);

            % Feedback in command window
            disp('Images successfully imported :');
            disp(sortedList);
            
        end

        % Value changed function: EarliestDropDown
        function EarliestDropDownValueChanged(app, event)
           app.idxEarliest = find(strcmp(app.ImageList, app.EarliestDropDown.Value));
            
        end

        % Value changed function: LatestDropDown
        function LatestDropDownValueChanged(app, event)
            app.idxLatest = find(strcmp(app.ImageList, app.LatestDropDown.Value));
            
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            % --- Validate selection ---
            
            if isempty(app.idxEarliest) || isempty(app.idxLatest)
                uialert(app.UIFigure, 'Please select both the earliest and latest images.', 'Error');
                return;
            end

            if app.idxLatest <= app.idxEarliest
                uialert(app.UIFigure, 'The latest image must be later than the earliest image.', 'Error');
                return;
            end

            % --- Reload aligned images based on current PreSwitch state ---
            if isempty(app.ImageFolder) || isempty(app.ImageList)
                uialert(app.UIFigure, 'No folder has been loaded yet. Please Browse first.', 'Error');
                return;
            end

            app.showModeControls('Nothing');

            if app.PreprocessingEnabled
                disp('Analyze: Preprocessing is ON. Calling registration module...');
                [app.AlignedImagesGray, app.AlignedImagesRGB, app.TransformParams] = register_images(app.ImageFolder, app.ImageList);
                app.AlignedImages = app.AlignedImagesRGB;


            else
                disp('Analyze: Preprocessing is OFF. Loading raw images...');
                app.AlignedImages = cellfun(@(f) imread(fullfile(app.ImageFolder,f)), app.ImageList, 'UniformOutput', false);
                app.TransformParams = [];
            end
            % app.AlignedImages = app.AlignedImagesRGB;

            % --- Extract selected range ---
            imagesForAnalysis = app.AlignedImages(app.idxEarliest : app.idxLatest);

            % --- Run detection for all modes ---
            options = struct();
            options.abs_threshold = 0.2;

            modes = {'absolute', 'speed', 'size'};
            app.DetectionResults = struct();

            for i = 1:numel(modes)
                try
                    disp(['Running detection for mode: ' modes{i}]);
                    result = detect_changes(imagesForAnalysis, modes{i}, options);
                    app.DetectionResults.(modes{i}) = result;
                catch ME
                    warning('Detection for mode %s failed: %s', modes{i}, ME.message);
                    app.DetectionResults.(modes{i}) = [];
                end
            end

            disp('All detection modes processed and results stored.');

            % --- Default display to first image ---
            imshow(imagesForAnalysis{1}, 'Parent', app.UIAxes);
            axis(app.UIAxes, 'off');
            app.currentIndex = 1;
            figure(app.UIFigure);
            % --- Notify user ---
            uialert(app.UIFigure, 'Analysis complete. Use sidebar buttons to view results.', 'Ready');


        end

        % Button pushed function: SpeedButton
        function SpeedButtonPushed(app, event)
            app.CurrentMode = 'Speed';
            app.CurrentDetectionMode = 'speed';
            app.showModeControls('Speed');
            app.showDetectionResult('speed');

        end

        % Button pushed function: ViewButton
        function ViewButtonPushed(app, event)
            app.CurrentMode = 'EarliestLatest';

          
            if app.idxEarliest < app.idxLatest
                app.EarliestLatestSubset = app.AlignedImages(app.idxEarliest : app.idxLatest);
            else
                app.EarliestLatestSubset = {};  
            end

            app.currentIndex = 1;
            app.showModeControls('EarliestLatest');

            % earliest
            % imshow(app.AlignedImages{app.idxEarliest}, 'Parent', app.UIAxes);
        end

        % Button pushed function: SliderButton
        function SliderButtonPushed(app, event)
            app.CurrentMode = 'Slider';
            app.showModeControls('Slider');
        end

        % Button pushed function: AbsoluteButton
        function AbsoluteButtonPushed(app, event)
            app.CurrentMode = 'Absolute';
            app.CurrentDetectionMode = 'absolute';
            app.showModeControls('Absolute');

            % Ensure test results are available
            if isempty(app.DetectionResults) || ~isfield(app.DetectionResults, 'absolute')
                uialert(app.UIFigure, 'Please run Analyze first.', 'Error');
                return;
            end

            % Save grayscale and color results separately
            app.AbsolutePagesGray = app.DetectionResults.absolute.visual_gray;
            app.AbsolutePagesRGB  = app.DetectionResults.absolute.visual_rgb;

            % Switch the version to be displayed according to the current value of the drop-down menu
            if strcmp(app.ColorModeDropDown.Value, 'Gray')
                app.AbsolutePages = app.AbsolutePagesGray;
            else
                app.AbsolutePages = app.AbsolutePagesRGB;
            end

            app.currentIndex = 1;

            % Show first page
            if ~isempty(app.AbsolutePages)
                imshow(app.AbsolutePages{1}, 'Parent', app.UIAxes);
                axis(app.UIAxes, 'off');
                app.PageIndicatorField.Value = sprintf('1 / %d', numel(app.AbsolutePages));
            else
                cla(app.UIAxes);
                app.PageIndicatorField.Value = '0 / 0';
            end
        end

        % Button pushed function: TimelapseButton
        function TimelapseButtonPushed(app, event)
            app.CurrentMode = 'Time-lapse';
            if isempty(app.idxEarliest) || isempty(app.idxLatest)
                uialert(app.UIFigure, 'Please select both Earliest and Latest images.', 'Error');
                return;
            end

            if app.idxLatest <= app.idxEarliest
                uialert(app.UIFigure, 'Latest image must be after Earliest image.', 'Error');
                return;
            end

            % Generating time sequence
            app.TimelapseSequence = app.AlignedImages(app.idxEarliest : app.idxLatest);
            app.showModeControls('Time-lapse');
            % Check quantity
            if numel(app.TimelapseSequence) < 10
                uialert(app.UIFigure, 'Need at least 10 images for time-lapse.', 'Error');
                return;
            end
            app.showModeControls('Time-lapse');

        end

        % Button pushed function: EarliestButton
        function EarliestButtonPushed(app, event)
            app.currentIndex = 1;
            app.updateImageDisplay();
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            if app.currentIndex > 1
                app.currentIndex = app.currentIndex - 1;
            end
            app.updateImageDisplay();
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if strcmp(app.CurrentMode, 'EarliestLatest')
                maxCount = numel(app.EarliestLatestSubset);
            else
                maxCount = numel(app.AlignedImages);
            end

            if app.currentIndex < maxCount
                app.currentIndex = app.currentIndex + 1;
            end

            app.updateImageDisplay();
        end

        % Button pushed function: LatestButton
        function LatestButtonPushed(app, event)
            if strcmp(app.CurrentMode, 'EarliestLatest')
                app.currentIndex = numel(app.EarliestLatestSubset);
            else
                app.currentIndex = numel(app.AlignedImages);
            end

            app.updateImageDisplay();
        end

        % Value changed function: ModeSlider
        function ModeSliderValueChanged(app, event)
            app.ModeSlider.Value = event.Value;
            app.updateSliderBlend();
            
        end

        % Value changing function: ModeSlider
        function ModeSliderValueChanging(app, event)
            app.ModeSlider.Value = event.Value;
            app.updateSliderBlend();
            
        end

        % Value changed function: PreSwitch
        function PreSwitchValueChanged(app, event)
            val = app.PreSwitch.Value;
            if strcmp(val, 'On')
                app.PreprocessingEnabled = true;
                disp('Preprocessing ENABLED');
            else
                app.PreprocessingEnabled = false;
                disp('Preprocessing DISABLED');
            end
        end

        % Button pushed function: SizeButton
        function SizeButtonPushed(app, event)
            app.CurrentMode = 'Size';
            app.CurrentDetectionMode = 'size';
            app.showModeControls('Size');
            app.showDetectionResult('size');
        end

        % Button pushed function: LandUseButton
        function LandUseButtonPushed(app, event)
            app.CurrentMode = 'LandUse';
            app.CurrentDetectionMode = 'landuse';
            app.showModeControls('LandUse');

            % Validate range
            if isempty(app.idxEarliest) || isempty(app.idxLatest)
                uialert(app.UIFigure, 'Please select both the earliest and latest images.', 'Error');
                return;
            end

            if app.idxLatest <= app.idxEarliest
                uialert(app.UIFigure, 'The latest image must be after the earliest image.', 'Error');
                return;
            end

            % Ask user for tile size denominator
            answer = inputdlg('Enter tile size denominator (e.g. 10–50):','Tile Size',[1 35],{'10'});
            if isempty(answer)
                % User canceled
                return;
            end
            tileParam = str2double(answer{1});
            if isnan(tileParam) || tileParam < 5 || tileParam > 100
                uialert(app.UIFigure, 'Invalid tile size. Please enter a number between 5 and 100.', 'Error');
                return;
            end

            % Use only the selected range for land use detection
            if app.PreprocessingEnabled
                subset = app.AlignedImagesRGB(app.idxEarliest : app.idxLatest);
            else
                subset = app.AlignedImages(app.idxEarliest : app.idxLatest);
            end

            try
                result = compute_landuse(subset, tileParam);
                textResult = result.text;
            catch ME
                textResult = ['Error: ' ME.message];
            end

            app.ResultLandUse.Text = textResult;
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            if isempty(app.TimelapseSequence) || ~iscell(app.TimelapseSequence)
                uialert(app.UIFigure, 'Time-lapse sequence is empty. Please click "Time-lapse" again.', 'Error');
                return;
            end

            % Play Loop
            nFrames = numel(app.TimelapseSequence);
            for i = 1:nFrames
                img = app.TimelapseSequence{i};
                imshow(img, 'Parent', app.UIAxes);
                axis(app.UIAxes, 'off');
                pause(0.1);  % speed set
            end

            disp('Time-lapse playback completed.');
        end

        % Button pushed function: OutputButton
        function OutputButtonPushed(app, event)
            % check
            if isempty(app.CurrentMode)
                uialert(app.UIFigure, 'No mode selected.', 'Error');
                return;
            end

            % The Save File dialog box pops up
            [file, path] = uiputfile({'*.jpg';'*.gif'}, 'Save As');
            if isequal(file,0) || isequal(path,0)
                disp('User canceled save.');
                return;
            end
            fullFileName = fullfile(path, file);

            % Save
            switch app.CurrentMode
                case 'Time-lapse'
                    
                    if isempty(app.TimelapseSequence)
                        uialert(app.UIFigure, 'No time-lapse sequence to export.', 'Error');
                        return;
                    end

                    %  GIF
                    for idx = 1:numel(app.TimelapseSequence)
                        [A, map] = rgb2ind(im2uint8(app.TimelapseSequence{idx}), 256);
                        if idx == 1
                            imwrite(A, map, fullFileName, 'gif', 'LoopCount', inf, 'DelayTime', 0.2);
                        else
                            imwrite(A, map, fullFileName, 'gif', 'WriteMode', 'append', 'DelayTime', 0.2);
                        end
                    end
                    uialert(app.UIFigure, 'Time-lapse GIF exported!', 'Success');

                otherwise
                    % jpg
                    F = getframe(app.UIAxes);
                    imwrite(F.cdata, fullFileName, 'jpg');
                    uialert(app.UIFigure, 'Image exported as JPG!', 'Success');
            end
            figure(app.UIFigure);
            disp(['Exported to: ' fullFileName]);
        end

        % Value changed function: ColorModeDropDown
        function ColorModeDropDownValueChanged(app, event)
            if strcmp(app.CurrentMode, 'Absolute')
                if strcmp(app.ColorModeDropDown.Value, 'Gray')
                    app.AbsolutePages = app.DetectionResults.absolute.visual_gray;
                else
                    app.AbsolutePages = app.DetectionResults.absolute.visual_rgb;
                end
                app.updateImageDisplay();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image Display')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Tag = 'DisplayAxes';
            colormap(app.UIAxes, 'sky')
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Tag = 'Menu';
            app.GridLayout2 = uigridlayout(app.Panel_3);
            app.EarliestDropDownLabel = uilabel(app.GridLayout2);
            app.EarliestDropDownLabel.Text = 'Earliest';
            app.EarliestDropDown = uidropdown(app.GridLayout2);
            app.EarliestDropDown.ValueChangedFcn = createCallbackFcn(app, @EarliestDropDownValueChanged, true);
            app.EarliestDropDown.Tag = 'EarliestDropDown';
            app.LatestDropDownLabel = uilabel(app.GridLayout2);
            app.LatestDropDownLabel.Text = 'Latest ';
            app.LatestDropDown = uidropdown(app.GridLayout2);
            app.LatestDropDown.ValueChangedFcn = createCallbackFcn(app, @LatestDropDownValueChanged, true);
            app.LatestDropDown.Tag = 'LatestDropDown';
            app.AnalyzeButton = uibutton(app.GridLayout2, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Tag = 'AnalyzeButton';
            app.AnalyzeButton.Text = 'Analyze';
            app.Panel_2 = uipanel(app.UIFigure);
            app.GridLayout3 = uigridlayout(app.Panel_2);
            app.ViewButton = uibutton(app.GridLayout3, 'push');
            app.ViewButton.ButtonPushedFcn = createCallbackFcn(app, @ViewButtonPushed, true);
            app.ViewButton.Tag = 'EarliestButton';
            app.ViewButton.Tooltip = {'View selected earliest to latest images in sequence'};
            app.ViewButton.Text = 'View';
            app.SliderButton = uibutton(app.GridLayout3, 'push');
            app.SliderButton.ButtonPushedFcn = createCallbackFcn(app, @SliderButtonPushed, true);
            app.SliderButton.Tag = 'SliderButton';
            app.SliderButton.Tooltip = {'Compare earliest and latest images with an adjustable slider'};
            app.SliderButton.Text = 'Slider';
            app.TimelapseButton = uibutton(app.GridLayout3, 'push');
            app.TimelapseButton.ButtonPushedFcn = createCallbackFcn(app, @TimelapseButtonPushed, true);
            app.TimelapseButton.Tag = 'TimelapseButton';
            app.TimelapseButton.Tooltip = {'Generate a simple time-lapse animation from all images'};
            app.TimelapseButton.Text = 'Time-lapse';
            app.AbsoluteButton = uibutton(app.GridLayout3, 'push');
            app.AbsoluteButton.ButtonPushedFcn = createCallbackFcn(app, @AbsoluteButtonPushed, true);
            app.AbsoluteButton.Tag = 'OverlayButton';
            app.AbsoluteButton.Tooltip = {'Detect pixel-wise absolute changes between images'};
            app.AbsoluteButton.Text = 'Absolute';
            app.SpeedButton = uibutton(app.GridLayout3, 'push');
            app.SpeedButton.ButtonPushedFcn = createCallbackFcn(app, @SpeedButtonPushed, true);
            app.SpeedButton.Tag = 'DifferenceButton';
            app.SpeedButton.Tooltip = {'Analyze how fast regions change over the image sequence'};
            app.SpeedButton.Text = 'Speed';
            app.SizeButton = uibutton(app.GridLayout3, 'push');
            app.SizeButton.ButtonPushedFcn = createCallbackFcn(app, @SizeButtonPushed, true);
            app.SizeButton.Tag = 'LatestButton';
            app.SizeButton.Tooltip = {'Highlight and classify change regions by size'};
            app.SizeButton.Text = 'Size';
            app.LandUseButton = uibutton(app.GridLayout3, 'push');
            app.LandUseButton.ButtonPushedFcn = createCallbackFcn(app, @LandUseButtonPushed, true);
            app.LandUseButton.Tooltip = {'Classify detected changes by land use type'};
            app.LandUseButton.Text = 'Land Use';
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Tag = 'ControlPanel';
            app.ColorModeLabel = uilabel(app.ControlPanel);
            app.ColorModeLabel.Visible = 'off';
            app.ColorModeLabel.Text = 'Color Mode';
            app.ColorModeDropDown = uidropdown(app.ControlPanel);
            app.ColorModeDropDown.Items = {'Gray', 'RGB'};
            app.ColorModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorModeDropDownValueChanged, true);
            app.ColorModeDropDown.Visible = 'off';
            app.ColorModeDropDown.Value = 'Gray';
            app.Label = uilabel(app.ControlPanel);
            app.Label.Text = '';
            app.ModeSlider = uislider(app.ControlPanel);
            app.ModeSlider.ValueChangedFcn = createCallbackFcn(app, @ModeSliderValueChanged, true);
            app.ModeSlider.ValueChangingFcn = createCallbackFcn(app, @ModeSliderValueChanging, true);
            app.ModeSlider.Visible = 'off';
            app.PlayButton = uibutton(app.ControlPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Visible = 'off';
            app.PlayButton.Text = 'Play';
            app.OutputButton = uibutton(app.ControlPanel, 'push');
            app.OutputButton.ButtonPushedFcn = createCallbackFcn(app, @OutputButtonPushed, true);
            app.OutputButton.Text = 'Output';
            app.LatestButton = uibutton(app.ControlPanel, 'push');
            app.LatestButton.ButtonPushedFcn = createCallbackFcn(app, @LatestButtonPushed, true);
            app.LatestButton.Visible = 'off';
            app.LatestButton.Text = 'Latest';
            app.NextButton = uibutton(app.ControlPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Visible = 'off';
            app.NextButton.Text = 'Next';
            app.PreviousButton = uibutton(app.ControlPanel, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Visible = 'off';
            app.PreviousButton.Text = 'Previous';
            app.EarliestButton = uibutton(app.ControlPanel, 'push');
            app.EarliestButton.ButtonPushedFcn = createCallbackFcn(app, @EarliestButtonPushed, true);
            app.EarliestButton.Visible = 'off';
            app.EarliestButton.Text = 'Earliest';
            app.Label_2 = uilabel(app.ControlPanel);
            app.Label_2.Visible = 'off';
            app.Label_2.Text = '';
            app.PageIndicatorField = uitextarea(app.ControlPanel);
            app.PageIndicatorField.Editable = 'off';
            app.PageIndicatorField.Visible = 'off';
            app.Slider2Label = uilabel(app.ControlPanel);
            app.Slider2Label.Visible = 'off';
            app.Slider2Label.Text = 'Slider2';
            app.Slider2 = uislider(app.ControlPanel);
            app.Slider2.MajorTicks = [];
            app.Slider2.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.Slider2.Visible = 'off';
            app.ResultLandUse = uilabel(app.ControlPanel);
            app.ResultLandUse.Visible = 'off';
            app.ResultLandUse.Text = '';
            app.SpeedColor = uiimage(app.ControlPanel);
            app.SpeedColor.Visible = 'off';
            app.SpeedColor.ImageSource = fullfile(pathToMLAPP, 'SpeedColor.png');
            app.Panel_4 = uipanel(app.UIFigure);
            app.Panel_4.Title = 'Panel';
            app.BrowseButton = uibutton(app.Panel_4, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Tag = 'ImportButton';
            app.BrowseButton.Text = 'Browse';
            app.EditFieldTextAreaLabel = uilabel(app.Panel_4);
            app.EditFieldTextAreaLabel.Text = 'Edit Field';
            app.EditFieldTextArea = uitextarea(app.Panel_4);
            app.EditFieldTextArea.Editable = 'off';
            app.PreSwitchLabel = uilabel(app.Panel_4);
            app.PreSwitchLabel.Text = 'Pre';
            app.PreSwitch = uiswitch(app.Panel_4, 'slider');
            app.PreSwitch.ValueChangedFcn = createCallbackFcn(app, @PreSwitchValueChanged, true);
            app.PreSwitch.Tooltip = {'Enable or disable preprocessing (alignment and brightness correction)'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end